classdef electricFieldSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CampoDropDownLabel          matlab.ui.control.Label
        tipoCampo                   matlab.ui.control.DropDown
        GraficarButton              matlab.ui.control.Button
        limpiar_button              matlab.ui.control.Button
        NmerodeCargasporPlacaLabel  matlab.ui.control.Label
        numCargas_input             matlab.ui.control.EditField
        MagnituddeCargasLabel       matlab.ui.control.Label
        magnitudCargas_input        matlab.ui.control.EditField
        PosicinXcargacentralizquierdaLabel  matlab.ui.control.Label
        posX_input                  matlab.ui.control.EditField
        PosicinYcargacentralizquierdaLabel  matlab.ui.control.Label
        posY_input                  matlab.ui.control.EditField
        EspacioentreplacasLabel     matlab.ui.control.Label
        espacioPlacas_input         matlab.ui.control.EditField
        mensajeValidacion           matlab.ui.control.Label
        grafica                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        % Función para graficar campo uniforme
        function graficarCampoUniforme(app,k,cargaPositiva,cargaNegativa,n,posicionIzquierdaX,posicionDerechaX,posicionInicialY)

            cla(app.grafica,'reset'); %resetear figura
            
            % Calcular posiciones Y a partir de la carga inferior izquierda
            inferior = -0.2*(floor(n/2))+posicionInicialY;
            posicionesCargasY = zeros(n,1);
            for i = 1:n
               posicionesCargasY(i) = inferior;
               inferior = inferior + 0.2;
            end
            
            %Se crea un meshgrid
            x = posicionInicialY-10:0.2:posicionInicialY+10;
            y = x;
            [x1,y1] = meshgrid(x,y);
            
            % Se inicializan vectores para guardar componentes de campo
            componentesX = zeros(n*2,1);
            componentesY = zeros(n*2,1);

            % Se itera sobre la matriz meshgrid para calcular el campo total en cada
            % punto de la matriz usando la Ley de Coulomb y distribuciones de carga
            for row = 1:length(x1)
                for column = 1:length(y1)
                    
                    % Se toma la posición actual del meshgrid
                    posX = x1(row,column);
                    posY = y1(row,column);
                    
                    % Se itera sobre el número total de cargas. Se calcula el campo
                    % causado por cada carga en el punto actual usando Ley de Couloumb
                    % La primera mitad de iteraciones es para cargas positivas, y la
                    % segunda mitad es para cargas negativas.
                    for carga = 1:2*n
                        if carga <= n
                            %Cargas Izquierda Positivas
                            rx = posX-posicionIzquierdaX;
                            ry = posY-posicionesCargasY(carga);
                            rcubo = (sqrt(rx^2+ry^2))^3;
                            componentesX(carga) = (k*cargaPositiva*rx/rcubo);
                            componentesY(carga) = (k*cargaPositiva*ry/rcubo);
                        else
                            %Cargas derecha negativas
                            rx = posX-posicionDerechaX;
                            ry = posY-posicionesCargasY(carga-n);
                            rcubo = (sqrt(rx^2+ry^2))^3;
                            componentesX(carga) = (k*cargaNegativa*rx/rcubo);
                            componentesY(carga) = (k*cargaNegativa*ry/rcubo);
                        end
                    end
                    
                    %Se suma las componentes para obtener el campo total en el punto y se
                    %resetean los vectores. El campo total de cada punto se guarda en
                    %las matrices Ex y Ey que tienen el mismo tamaño que el meshgrid
                    Ex(row,column) = sum(componentesX);
                    Ey(row,column) = sum(componentesY);
                    componentesX = zeros(n*2,1);
                    componentesY = zeros(n*2,1);
                end
            end
            
            %Graficar los vectores con quiver
            E = sqrt(Ex.^2 + Ey.^2);
            u = Ex ./ E;
            v = Ey ./ E;
            quiver(app.grafica,x,y,u,v,0.5,'magenta')
            
            %Graficar polígonos
            for i = 1:n
                graficarPoligono(app,posicionDerechaX,posicionesCargasY(i),cargaNegativa)
                graficarPoligono(app,posicionIzquierdaX,posicionesCargasY(i),cargaPositiva)
            end
            
            %Ejes
            ejeYnegativo = posicionesCargasY(1)-1.5;
            ejeYpositivo = posicionesCargasY(length(posicionesCargasY))+1.5;
            ejeXnegativo = posicionIzquierdaX-(ejeYpositivo-posicionInicialY);
            ejeXpositivo = posicionDerechaX+(ejeYpositivo-posicionInicialY);
            axis(app.grafica,'equal');
            axis(app.grafica,[ejeXnegativo ejeXpositivo ejeYnegativo ejeYpositivo])
            title(app.grafica,'Placas simétricas. Múltiples cargas con diferente signo')
            xlabel(app.grafica,'Valores en X')
            ylabel(app.grafica,'Valores en Y')
            
        end
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function graficarCampoNoUniforme(app,k,cargaPositiva,cargaNegativa,n,posicionIzquierdaX,posicionDerechaX,posicionInicialY)
            
            cla(app.grafica,'reset'); %resetear figura
            
            % Posiciones en Y. Se calculan las posiciones y se guardan en vectores.
            % Hay un espacio de 0.2 entr cargas positivas y un espacio de 0.1 entre
            % cargas negativas.
            posYCargasPositivas = zeros(n,1);
            posYCargasNegativas = zeros(n,1);
            posicion = -0.2*(floor(n/2))+posicionInicialY; %carga inferior izquierda
            posicion2 = posicion/2; %carga inferior derecha
            for i = 1:n
               posYCargasPositivas(i) = posicion;
               posicion = posicion + 0.2;
               posYCargasNegativas(i) = posicion2;
               posicion2 = posicion2 + 0.1;
            end
            
            %Se crea un meshgrid
            x = posicionInicialY-20:0.2:posicionInicialY+20;
            y = x;
            [x1,y1] = meshgrid(x,y);
            
            % Se inicializan vectores para guardar componentes de campo
            componentesX = zeros(n*2,1);
            componentesY = zeros(n*2,1);
            
            % Se itera sobre la matriz meshgrid para calcular el campo total en cada
            % punto de la matriz usando la Ley de Coulomb y distribuciones de carga
            for row = 1:length(x1)
                for column = 1:length(y1)
                    
                    % Se toma la posición actual del meshgrid
                    posX = x1(row,column);
                    posY = y1(row,column);
                    
                    % Se itera sobre el número total de cargas. Se calcula el campo
                    % causado por cada carga en el punto actual usando Ley de Couloumb
                    % La primera mitad de iteraciones es para cargas positivas, y la
                    % segunda mitad es para cargas negativas.
                    for carga = 1:2*n
                        if carga <= n
                            %Cargas Izquierda Positivas
                            rx = posX-posicionIzquierdaX;
                            ry = posY-posYCargasPositivas(carga);
                            rcubo = (sqrt(rx^2+ry^2))^3;
                            componentesX(carga) = (k*cargaPositiva*rx/rcubo);
                            componentesY(carga) = (k*cargaPositiva*ry/rcubo);
                        else
                            %Cargas derecha negativas
                            rx = posX-posicionDerechaX;
                            ry = posY-posYCargasNegativas(carga-n);
                            rcubo = (sqrt(rx^2+ry^2))^3;
                            componentesX(carga) = (k*cargaNegativa*rx/rcubo);
                            componentesY(carga) = (k*cargaNegativa*ry/rcubo);
                        end
                    end
                    
                    %Se suma las componentes para obtener el campo total en el punto y se
                    %resetean los vectores. El campo total de cada punto se guarda en
                    %las matrices Ex y Ey que tienen el mismo tamaño que el meshgrid
                    Ex(row,column) = sum(componentesX);
                    Ey(row,column) = sum(componentesY);
                    componentesX = zeros(n*2,1);
                    componentesY = zeros(n*2,1);
                end
            end
            
            %Graficar los vectores con quiver
            fprintf('\nGraficando. Esto puede tardar unos segundos...\n')
            E = sqrt(Ex.^2 + Ey.^2);
            u = Ex ./ E;
            v = Ey ./ E;
            quiver(app.grafica,x,y,u,v,0.5,'magenta')
            
            %Graficar cargas
            for i = 1:n
                graficarPoligono(app,posicionDerechaX,posYCargasNegativas(i),cargaNegativa)
                graficarPoligono(app,posicionIzquierdaX,posYCargasPositivas(i),cargaPositiva)
            end
            
            % Ejes
            ejeYnegativo = posYCargasPositivas(1)-1.5;
            ejeYpositivo = posYCargasPositivas(length(posYCargasPositivas))+1.5;
            ejeXnegativo = posicionIzquierdaX-(ejeYpositivo-posicionInicialY);
            ejeXpositivo = posicionDerechaX+(ejeYpositivo-posicionInicialY);
            axis(app.grafica,'equal');
            axis(app.grafica,[ejeXnegativo ejeXpositivo ejeYnegativo ejeYpositivo])
            title(app.grafica,'Placas asimétricas. Múltiples cargas con diferente signo')
            xlabel(app.grafica,'Valores en X')
            ylabel(app.grafica,'Valores en Y')             
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
     
        %Función para graficar los polígonos. Recibe la posición de una carga
        %y su magnitud para saber si graficar positiva o negativa. 
        function graficarPoligono(app,posicionX,posicionY,carga)
            hold(app.grafica,"on")
            extension = 0.2;
            ajustePosicion = extension/2; 
            pos = [posicionX-ajustePosicion posicionY-ajustePosicion extension extension];
            rectangle(app.grafica,'Position',pos,'Curvature',[1 1]);
            
            %Color y signos de la carga
            if carga > 0
                rectangle(app.grafica,'Position',pos,'Curvature',[1 1],'FaceColor','r');
                %Signo positivo. Se crean dos polígonos blancos
                plot(app.grafica,[(posicionX-0.07) (posicionX+0.07)], posicionY*[1 1],'Color','white','LineWidth',3); %horizontal
                plot(app.grafica,[posicionX posicionX],[posicionY-0.06 posicionY+.06], 'Color','white','LineWidth',3); %vertical
            else
                rectangle(app.grafica,'Position',pos,'Curvature',[1 1],'FaceColor','b');
                %Signo negativo. Se crea un polígono
                plot(app.grafica,[(posicionX-0.07) (posicionX+0.07)], posicionY*[1 1],'Color','white','LineWidth',3);
            end  
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
        
            % LEER ENTRADAS DEL USUARIO
            %corregir input validation
            
                %Leer magnitud de cargas. Iguales pero signo opuesto
            cargaPositiva = textscan(app.magnitudCargas_input.Value,'%f');
            cargaPositiva = cargaPositiva{1};
            cargaNegativa = -cargaPositiva; 
            
                %Leer el número de cargas por placa. Se vuelve a pedir
                %mientras no sea un entero positivo
            n = textscan(app.numCargas_input.Value,'%f');
            n = n{1};
            
                %Leer posición de la carga central de la placa izquierda. 
            posicionIzquierdaX = textscan(app.posX_input.Value,'%f');
            posicionInicialY = textscan(app.posY_input.Value,'%f');
            posicionIzquierdaX = posicionIzquierdaX{1};
            posicionInicialY = posicionInicialY{1};

                %Leer espacio entre placas
            espacioEntrePlacas = textscan(app.espacioPlacas_input.Value,'%f');
            espacioEntrePlacas = espacioEntrePlacas {1};
            
                %Constante K
                k = 8.99e9;
            
                % Se calcula la posición en X de la placa izquierda de acuerdo al espacio ingresado. 
            posicionDerechaX = posicionIzquierdaX+espacioEntrePlacas;
            
                %Leer tipo de campo a graficar y ejecutar opción
            opcion = app.tipoCampo.Value;
            if  strcmpi(opcion,'Uniforme')
                graficarCampoUniforme(app,k,cargaPositiva,cargaNegativa,n,posicionIzquierdaX,posicionDerechaX,posicionInicialY);
            elseif strcmpi(opcion,'No uniforme')
                graficarCampoNoUniforme(app,k,cargaPositiva,cargaNegativa,n,posicionIzquierdaX,posicionDerechaX,posicionInicialY);
            end
            
            
        end

        % Button pushed function: limpiar_button
        function limpiar_buttonButtonPushed(app, event)
             cla(app.grafica,'reset');
             app.espacioPlacas_input.Value = '';
             app.posY_input.Value = '';
             app.posX_input.Value = '';
             app.magnitudCargas_input.Value = '';
             app.numCargas_input.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CampoDropDownLabel = uilabel(app.UIFigure);
            app.CampoDropDownLabel.Text = 'Campo';
            app.tipoCampo = uidropdown(app.UIFigure);
            app.tipoCampo.Items = {'Uniforme', 'No uniforme'};
            app.tipoCampo.Value = 'Uniforme';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.limpiar_button = uibutton(app.UIFigure, 'push');
            app.limpiar_button.ButtonPushedFcn = createCallbackFcn(app, @limpiar_buttonButtonPushed, true);
            app.limpiar_button.Text = 'Limpiar';
            app.NmerodeCargasporPlacaLabel = uilabel(app.UIFigure);
            app.NmerodeCargasporPlacaLabel.Text = 'Número de Cargas por Placa';
            app.numCargas_input = uieditfield(app.UIFigure, 'text');
            app.MagnituddeCargasLabel = uilabel(app.UIFigure);
            app.MagnituddeCargasLabel.Text = 'Magnitud de Cargas';
            app.magnitudCargas_input = uieditfield(app.UIFigure, 'text');
            app.PosicinXcargacentralizquierdaLabel = uilabel(app.UIFigure);
            app.PosicinXcargacentralizquierdaLabel.Text = 'Posición X carga central izquierda';
            app.posX_input = uieditfield(app.UIFigure, 'text');
            app.PosicinYcargacentralizquierdaLabel = uilabel(app.UIFigure);
            app.PosicinYcargacentralizquierdaLabel.Text = 'Posición Y carga central izquierda';
            app.posY_input = uieditfield(app.UIFigure, 'text');
            app.EspacioentreplacasLabel = uilabel(app.UIFigure);
            app.EspacioentreplacasLabel.Text = 'Espacio entre placas';
            app.espacioPlacas_input = uieditfield(app.UIFigure, 'text');
            app.mensajeValidacion = uilabel(app.UIFigure);
            app.mensajeValidacion.Text = 'El número de cargas tiene que ser un entero positivo';
            app.grafica = uiaxes(app.UIFigure);
            title(app.grafica, 'Title')
            xlabel(app.grafica, 'X')
            ylabel(app.grafica, 'Y')
            zlabel(app.grafica, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = electricFieldSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end